classdef GUI_v3_07 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPOT30GUIUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveGUIMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        YouTubeTutorialsMenu            matlab.ui.container.Menu
        SoftwareInstallationMenu        matlab.ui.container.Menu
        SoftwareFolderStructureMenu     matlab.ui.container.Menu
        PhaseSpaceCodeMenu              matlab.ui.container.Menu
        ExperimentSourceCodeMenu        matlab.ui.container.Menu
        DeviceDriversSinksMenu          matlab.ui.container.Menu
        DeviceDriversSourcesMenu        matlab.ui.container.Menu
        CustomSimulinkLibraryMenu       matlab.ui.container.Menu
        MassPropertiesPanel             matlab.ui.container.Panel
        REDMassLabel                    matlab.ui.control.Label
        REDInertiaLabel                 matlab.ui.control.Label
        BLACKMassLabel                  matlab.ui.control.Label
        BLACKInertiaLabel               matlab.ui.control.Label
        BLUEMassLabel                   matlab.ui.control.Label
        BLUEInertiaLabel                matlab.ui.control.Label
        MassREDEditField                matlab.ui.control.NumericEditField
        InertiaREDEditField             matlab.ui.control.NumericEditField
        MassBLACKEditField              matlab.ui.control.NumericEditField
        InertiaBLACKEditField           matlab.ui.control.NumericEditField
        MassBLUEEditField               matlab.ui.control.NumericEditField
        InertiaBLUEEditField            matlab.ui.control.NumericEditField
        MassREDLabel                    matlab.ui.control.Label
        MassBLACKLabel                  matlab.ui.control.Label
        MassBLUELabel                   matlab.ui.control.Label
        InertiaREDLabel                 matlab.ui.control.Label
        InertiaBLACKLabel               matlab.ui.control.Label
        InertiaBLUELabel                matlab.ui.control.Label
        AuxParametersPanel              matlab.ui.container.Panel
        TimeLabel                       matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        RateLabel                       matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        ActivePlatformsPanel            matlab.ui.container.Panel
        REDCheckBox                     matlab.ui.control.CheckBox
        BLACKCheckBox                   matlab.ui.control.CheckBox
        BLUECheckBox                    matlab.ui.control.CheckBox
        ARMCheckBox                     matlab.ui.control.CheckBox
        ConfirmOptionsButton            matlab.ui.control.Button
        LoadSelectedDiagramButton       matlab.ui.control.Button
        AvailableDiagramsDropDownLabel  matlab.ui.control.Label
        AvailableDiagramsDropDown       matlab.ui.control.DropDown
        ExperimentPanel                 matlab.ui.container.Panel
        ExperimentOptionsPanel          matlab.ui.container.Panel
        StartExperimentButton           matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        SaveExperimentButton            matlab.ui.control.Button
        RealTimeTelemetryCheckBox       matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        REDControlsTab                  matlab.ui.container.Tab
        REDPucksOnButton                matlab.ui.control.Button
        REDPucksOffButton               matlab.ui.control.Button
        DeployCodeREDButton             matlab.ui.control.Button
        REDDebuggingPanel               matlab.ui.container.Panel
        FireREDThrustersButton          matlab.ui.control.Button
        PingREDButton                   matlab.ui.control.Button
        REDTextArea                     matlab.ui.control.TextArea
        REDConnectedLamp                matlab.ui.control.Lamp
        OpenREDCMDShell                 matlab.ui.control.Button
        BLACKControlsTab                matlab.ui.container.Tab
        BLACKPucksOnButton              matlab.ui.control.Button
        BLACKPucksOffButton             matlab.ui.control.Button
        DeployCodeBLACKButton           matlab.ui.control.Button
        BLACKDebuggingPanel             matlab.ui.container.Panel
        FireBLACKThrustersButton        matlab.ui.control.Button
        PingBLACKButton                 matlab.ui.control.Button
        BLACKConnectedLamp              matlab.ui.control.Lamp
        BLACKTextArea                   matlab.ui.control.TextArea
        OpenBLACKCMDShell               matlab.ui.control.Button
        BLUEControlsTab                 matlab.ui.container.Tab
        BLUEPucksOnButton               matlab.ui.control.Button
        BLUEPucksOffButton              matlab.ui.control.Button
        DeployCodeBLUEButton            matlab.ui.control.Button
        BLUEDebuggingPanel              matlab.ui.container.Panel
        FireBLUEThrustersButton         matlab.ui.control.Button
        PingBLUEButton                  matlab.ui.control.Button
        BLUETextArea                    matlab.ui.control.TextArea
        BLUEConnectedLamp               matlab.ui.control.Lamp
        OpenBLUECMDShell                matlab.ui.control.Button
        SimulationPanel                 matlab.ui.container.Panel
        ExperimentPreview               matlab.ui.control.UIAxes
        SimulationControlsPanel         matlab.ui.container.Panel
        RunSimulationButton             matlab.ui.control.Button
        PlayAnimationButton             matlab.ui.control.Button
        AnimationSpeedButtonGroup       matlab.ui.container.ButtonGroup
        AnimationSpeedSlider            matlab.ui.control.Slider
        StopAnimationButton             matlab.ui.control.StateButton
        SimExportOptionsPanel           matlab.ui.container.Panel
        CreateVideoCheckBox             matlab.ui.control.CheckBox
        SaveSimulationButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SPOT30Label                     matlab.ui.control.Label
        Version307Label                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        REDFigHdl   = [];
        BLACKFigHdl = [];
        BLUEFigHdl  = [];
        REDFigLine  = [];
        BLACKFigLine= [];
        BLUEFigLine = [];
        Interruptor = 0;
    end

    methods (Access = private)
        
        function [] = AddPlatform(app,platformSelection)
            
            app.ExperimentPreview.cla;
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigLine  = [];
            app.BLACKFigLine= [];
            app.BLUEFigLine = [];

            % Initialize the components of the GUI:
            xLength = 3.51155;  %[m]
            yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreview,[0 yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreview,"on");
            line(app.ExperimentPreview,[yLength yLength],[0 xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[yLength 0],[xLength xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[0 0],[xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreview,"on");    
            
            % Get the drop states for all three platforms:
            drop_states_RED   = evalin("base","drop_states_RED");
            drop_states_BLACK = evalin("base","drop_states_BLACK");
            drop_states_BLUE  = evalin("base","drop_states_BLUE");
            
            % For animations purposes, invert the angles:
            drop_states_RED(3)  = -drop_states_RED(3);
            drop_states_BLACK(3)= -drop_states_BLACK(3);
            drop_states_BLUE(3) = -drop_states_BLUE(3);
            
            % Draw the line showing the direction of the front face:
            C_bI_RED = [  cos(drop_states_RED(3)) sin(drop_states_RED(3))
                         -sin(drop_states_RED(3)) cos(drop_states_RED(3)) ];
                     
            Vec_RED  = C_bI_RED*[ 0.15
                                  0.0 ];
                              
            C_bI_BLACK = [  cos(drop_states_BLACK(3)) sin(drop_states_BLACK(3))
                           -sin(drop_states_BLACK(3)) cos(drop_states_BLACK(3)) ];
                     
            Vec_BLACK  = C_bI_BLACK*[ 0.15
                                      0.0 ];    
                              
            C_bI_BLUE = [  cos(drop_states_BLUE(3)) sin(drop_states_BLUE(3))
                          -sin(drop_states_BLUE(3)) cos(drop_states_BLUE(3)) ];
                     
            Vec_BLUE  = C_bI_BLUE*[ 0.15
                                    0.0 ];                              
            
            % Draw the relevant platforms:
            if (platformSelection == 1)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                
            elseif (platformSelection == 2)
                
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k");   
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 3)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 4)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 5)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                
            elseif (platformSelection == 6)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 7)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 8)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 9)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                                   
            elseif (platformSelection == 10)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b"); 
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 11)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            end


        end
        
        function [FigHdl] = DrawRectanglePrivate(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            a = param(1);
            b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;
            
            FigHdl = plot(app.ExperimentPreview,P(1,:)+a,P(2,:)+b,style);  
            
        end
        
        function [] = CombineData(~,DirName)
            
            Raspberrypi_MAT_stitcher(dir(DirName));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PopulateGUI(app)
            
            % Obtain a list of all .slx files present in the working directory, and populate the dropdown menu:
            MyFolderInfo = dir([cd '\*.slx']);
            FileNames    = {MyFolderInfo.name};
            app.AvailableDiagramsDropDown.Items = FileNames;
            
            % Add duration of experiment to GUI:
            app.TotalTimeEditField.Value = evalin('base','tsim');
            
            % Initialize the components of the GUI:
            xLength = 3.51155;  %[m]
            yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreview,[0 yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreview,"on");
            line(app.ExperimentPreview,[yLength yLength],[0 xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[yLength 0],[xLength xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[0 0],[xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreview,"on");
            
            % Assign the default value to be 0; this only changes temporarily when the
            % user clicks on the Run Simulation button.
            assignin("base","simMode",1);
            
            % Populate the platform parameters:
            model_param = evalin("base","model_param");
            baseRate = evalin("base","baseRate");
            
            app.SampleRateEditField.Value = 1/baseRate;
            app.MassREDEditField.Value = model_param(1);
            app.InertiaREDEditField.Value = model_param(2);
            app.MassBLACKEditField.Value = model_param(3);
            app.InertiaBLACKEditField.Value = model_param(4);
            app.MassBLUEEditField.Value = model_param(5);
            app.InertiaBLUEEditField.Value = model_param(6);
            
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.SaveSimulationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.ConfirmOptionsButton,'enable','off');
            set(app.AnimationSpeedSlider,'enable','off');
            set(app.RealTimeTelemetryCheckBox,'enable','off');
            
        end

        % Button pushed function: ConfirmOptionsButton
        function ConfirmOptionsButtonPushed(app, event)
            % Based on user selection in the GUI, assign variables to the workspace:
            % First, load in the selections:
            RED         = app.REDCheckBox.Value;
            BLACK       = app.BLACKCheckBox.Value;
            BLUE        = app.BLUECheckBox.Value;
            ARM         = app.ARMCheckBox.Value;
            ErrorMsg    = 0;
            
            FileName = app.AvailableDiagramsDropDown.Value;
            %Simulink.BlockDiagram.loadActiveConfigSet(FileName(1:end-4), 'DefaultConfiguration_Reduced.m');

            cs = getConfigSet(FileName(1:end-4),'Configuration');
            cs.set_param('Name', 'Configuration'); % Name
            cs.set_param('Description', ''); % Description
            cs_componentCC = CoderTarget.SettingsController;
            cs.attachComponent(cs_componentCC);
            cs.set_param('CoderTargetData', struct('UseCoderTarget',true,'TargetHardware','Raspberry Pi','ConnectionInfo',struct('XCPonTCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false),'TCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false)),'ExtMode',struct('Configuration','XCP on TCP/IP','signalBufferSize',1000000),'RTOS','Linux','RTOSBaseRateTaskPriority','40','DetectTaskOverruns',0,'Scheduler_interrupt_source',0,'BoardParameters',struct('DeviceAddress','192.168.0.101','Username','pi','Password','raspberry','BuildDir','/home/pi/ExperimentSoftware/'),'Runtime',struct('BuildAction','Build','RunOnBoot',false),'SPI',struct('SPI0CE0BusSpeed','500','SPI0CE1BusSpeed','500'),'CAN',struct('CANBusSpeed','500','CANOscillatorFrequency','16','InterruptPin','12','AllowAllFilter',1,'Buffer0IDType','Normal','AcceptanceMask0','0','AcceptanceFilter0','255','AcceptanceFilter1','255','Buffer1IDType','Normal','AcceptanceMask1','0','AcceptanceFilter2','255','AcceptanceFilter3','255','AcceptanceFilter4','255','AcceptanceFilter5','255'),'MQTT',struct('BrokerAddress','mqtt.thingspeak.com','Username','','Password','','ClientID',''),'SimulinkIO',struct('Enable_SimulinkIO',1,'TransportLayerType','TCP/IP','ModelTransportDataFcn','codertarget.raspi.ioclient.getModelTransportInfo','ServerDeployFcn','codertarget.raspi.ioclient.deployServer'),'DataVersion','2016.02'));   % CoderTargetData

            % Assign the results to the platformSelection variable:        
            if RED == 1 && BLACK == 0 && BLUE == 0 && ARM == 0
                assignin("base","platformSelection",1);     % RED
            elseif RED == 0 && BLACK == 1 && BLUE == 0 && ARM == 0
                assignin("base","platformSelection",2);     % BLACK
            elseif RED == 1 && BLACK == 1 && BLUE == 0 && ARM == 0
                assignin("base","platformSelection",3);     % RED + BLACK
            elseif RED == 1 && BLACK == 1 && BLUE == 0 && ARM == 1
                assignin("base","platformSelection",4);     % RED + BLACK + ARM
            elseif RED == 1 && BLACK == 0 && BLUE == 0 && ARM == 1
                assignin("base","platformSelection",5);     % RED + ARM
            elseif RED == 0 && BLACK == 0 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",6);     % BLUE
            elseif RED == 1 && BLACK == 0 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",7);     % BLUE + RED
            elseif RED == 1 && BLACK == 1 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",8);     % BLUE + RED + BLACK
            elseif RED == 0 && BLACK == 1 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",9);     % BLUE + BLACK
            elseif RED == 1 && BLACK == 0 && BLUE == 1 && ARM == 1
                assignin("base","platformSelection",10);    % BLUE + RED + ARM
            elseif RED == 1 && BLACK == 1 && BLUE == 1 && ARM == 1
                assignin("base","platformSelection",11);    % BLUE + RED + BLACK + ARM
            elseif RED == 0 && BLACK == 0 && BLUE == 0 && ARM == 0
                ed = errordlg("You need to select at least one platform.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                ErrorMsg = 1;
            else
                ed = errordlg("The combination of platforms selected has not been implemented yet.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                ErrorMsg = 1;
            end
            
            if (ErrorMsg~=1)                         
                AddPlatform(app,evalin("base","platformSelection"));
                set(app.RunSimulationButton,'enable','on');
                set(app.DeployCodeREDButton,'enable','on');
                set(app.DeployCodeBLACKButton,'enable','on');
                set(app.DeployCodeBLUEButton,'enable','on');
                set(app.StartExperimentButton,'enable','on');
                set(app.StopExperimentButton,'enable','on');
            end
           
        end

        % Button pushed function: LoadSelectedDiagramButton
        function LoadSelectedDiagramButtonPushed(app, event)
            % When the button is pressed, open the diagram currently selected in the dropdown menu:
            FileName = app.AvailableDiagramsDropDown.Value;
            set(app.ConfirmOptionsButton,'enable','on');
            open(FileName);                        
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
        % Get the selected files from the drop down menu:
        selectedFile = get(app.AvailableDiagramsDropDown,'Value');
        
        if (bdIsLoaded(selectedFile(1:(end-4)))==1)
        
            % Set workspace parameters in case the user has changed them in the GUI:
            model_param(1)=app.MassREDEditField.Value;
            model_param(2)=app.InertiaREDEditField.Value;
            model_param(3)=app.MassBLACKEditField.Value;
            model_param(4)=app.InertiaBLACKEditField.Value;
            model_param(5)=app.MassBLUEEditField.Value;
            model_param(6)=app.InertiaBLUEEditField.Value;
         
            assignin('base','model_param',model_param);
            assignin('base','baseRate',1/app.SampleRateEditField.Value);

            % Set the simulation mode in Simulink:
            set_param(selectedFile(1:(end-4)),'SimulationMode','normal');
        
            % Disable hardware mode:
            activeConfigObj = getActiveConfigSet(selectedFile(1:(end-4)));
            set_param(activeConfigObj,'HardwareBoard','None');
            
            % Set the simMode to 1 to indicate that a simulation is being
            % performed:
            assignin('base','simMode',1);
            assignin('base','WhoAmI',0);
                
            % Open a dialog indicating tht a simulation is being performed:
            simrun = dialog('Position',[300 300 250 150],'Name','Running Simulation...');
            
            uicontrol('Parent',simrun,...
                           'Style','text',...
                           'Position',[20 80 210 40],...
                           'String','Wait for simulation to complete.');   
                       
            options = simset('SrcWorkspace','base','DstWorkspace','base');
            sim(selectedFile(1:(end-4)),[],options)
            fprintf('Done.\n');
            set_param(activeConfigObj,'HardwareBoard','Raspberry Pi');
            close(simrun);
            
            assignin('base','simMode',0);
                
            set(app.PlayAnimationButton,'enable','on');
            set(app.StopAnimationButton,'enable','on');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.SaveSimulationButton,'enable','on');
            set(app.AnimationSpeedSlider,'enable','on');
        else
                ed = errordlg("The selected Simulink diagram has not been loaded.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);            
        end
        
        end

        % Button pushed function: PlayAnimationButton
        function PlayAnimationButtonPushed(app, event)
            W = evalin("base","whos"); 
            doesPacketExist = ismember('dataPacket',[W(:).name]);
            
            if app.CreateVideoCheckBox.Value == 1
                v = VideoWriter('SimulationVideo.avi','Motion JPEG AVI');
                v.Quality = 95;
                open(v);
            end

            
            if (sum(doesPacketExist) == 10)

                AddPlatform(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                
                % Load the data packet into the GUI workspace:
                dataPacketUp = evalin("base","dataPacket");
                
                % Downsample the data to reduce the number of points:
                [UniqueTime, UniqueIndex, ~] = unique(dataPacketUp(:,1));

                 NewTimeSlow = linspace(0,UniqueTime(end),UniqueTime(end)*(app.AnimationSpeedSlider.Value/10+1))';

                dataPacket = zeros(length(NewTimeSlow),size(dataPacketUp,2));
                
                for j = 1:size(dataPacketUp,2)
                    dataPacket(:,j) = interp1(UniqueTime,dataPacketUp(UniqueIndex,j),NewTimeSlow);       
                end
                                
                for i = 1:size(dataPacket,1)
                               
                    a = dataPacket(i,6);
                    b = dataPacket(i,5);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,7);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.REDFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_RED = [  cos(theta) sin(theta)
                                     -sin(theta) cos(theta) ];
                                 
                        Vec_RED  = C_bI_RED*[ 0.15
                                              0.0 ];
                                                      
                        app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                        app.REDFigLine.YData = [b,b+Vec_RED(1)];
                        app.REDFigHdl.XData = P(1,:)+a;
                        app.REDFigHdl.YData = P(2,:)+b;
                    end             
                    
                    a = dataPacket(i,26);
                    b = dataPacket(i,25);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,27);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLACKFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLACK = [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLACK  = C_bI_BLACK*[ 0.15
                                                  0.0 ];
                                                      
                        app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                        app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                        app.BLACKFigHdl.XData = P(1,:)+a;
                        app.BLACKFigHdl.YData = P(2,:)+b;
                        
                    end
                    
                    a = dataPacket(i,46);
                    b = dataPacket(i,45);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,47);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLUEFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLUE =  [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLUE  = C_bI_BLUE*[ 0.15
                                                0.0 ];
                                                      
                        app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                        app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                        app.BLUEFigHdl.XData = P(1,:)+a;
                        app.BLUEFigHdl.YData = P(2,:)+b;
                        
                    end                    
          
                    app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(dataPacket(i,1)),' s');
                    
                    if (app.StopAnimationButton.Value == 1)
                        app.StopAnimationButton.Value = 0;
                            if app.CreateVideoCheckBox.Value == 1
                                close(v);
                            end
                        break;
                    end
                    
                    pause(0.01)
                    drawnow limitrate
                    
                    if app.CreateVideoCheckBox.Value == 1

                           frame = getframe(app.ExperimentPreview);
                           writeVideo(v,frame);
                    end

                    
                end
                   
            else
                
                ed = errordlg("The variable dataPacket does not exist in the base workspace. Check that the simulation has been run, or ensure the variable is loaded.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                      
            end

            if app.CreateVideoCheckBox.Value == 1
                close(v);
            end
                       
        end

        % Callback function
        function StopAnimationButtonPushed(app, event)
            app.Interruptor = 1;
        end

        % Value changed function: MassREDEditField
        function MassREDEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: InertiaREDEditField
        function InertiaREDEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: MassBLACKEditField
        function MassBLACKEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: InertiaBLACKEditField
        function InertiaBLACKEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: MassBLUEEditField
        function MassBLUEEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');  
        end

        % Value changed function: InertiaBLUEEditField
        function InertiaBLUEEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.DeployCodeBLACKButton,'enable','off');
            set(app.DeployCodeBLUEButton,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Button pushed function: REDPucksOnButton
        function REDPucksOnButtonPushed(app, event)
            RED = raspberrypi('192.168.0.100','pi','raspberry');
            runModel(RED,'FloatPlatform');            
        end

        % Button pushed function: REDPucksOffButton
        function REDPucksOffButtonPushed(app, event)
            RED = raspberrypi('192.168.0.100','pi','raspberry');
            stopModel(RED,'FloatPlatform'); 
        end

        % Button pushed function: BLACKPucksOnButton
        function BLACKPucksOnButtonPushed(app, event)
            BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            runModel(BLACK,'FloatPlatform');  
        end

        % Button pushed function: BLACKPucksOffButton
        function BLACKPucksOffButtonPushed(app, event)
            BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            stopModel(BLACK,'FloatPlatform');              
        end

        % Button pushed function: BLUEPucksOnButton
        function BLUEPucksOnButtonPushed(app, event)
            BLUE = raspberrypi('192.168.0.102','pi','raspberry');
            runModel(BLUE,'FloatPlatform');              
        end

        % Button pushed function: BLUEPucksOffButton
        function BLUEPucksOffButtonPushed(app, event)
            BLUE = raspberrypi('192.168.0.102','pi','raspberry');
            stopModel(BLUE,'FloatPlatform');            
        end

        % Button pushed function: DeployCodeREDButton
        function DeployCodeREDButtonPushed(app, event)
            waitfor(warndlg('Warning: Check which "Deploy" option is currently selected under the "Hardware" simulink tab. It should automatically change to "Build" when this warning is dismissed. If "Build, Deploy, and Run" is selected, the experiment will start immediately after building!','Deployment Options'));
            
            FileName = app.AvailableDiagramsDropDown.Value;
            cs = getConfigSet(FileName(1:end-4),'Configuration');
            cs.set_param('Name', 'Configuration'); % Name
            cs.set_param('Description', ''); % Description
            cs_componentCC = CoderTarget.SettingsController;
            cs.attachComponent(cs_componentCC);
            cs.set_param('CoderTargetData', struct('UseCoderTarget',true,'TargetHardware','Raspberry Pi','ConnectionInfo',struct('XCPonTCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false),'TCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false)),'ExtMode',struct('Configuration','XCP on TCP/IP','signalBufferSize',1000000),'RTOS','Linux','RTOSBaseRateTaskPriority','40','DetectTaskOverruns',0,'Scheduler_interrupt_source',0,'BoardParameters',struct('DeviceAddress','192.168.0.100','Username','pi','Password','raspberry','BuildDir','/home/pi/ExperimentSoftware/'),'Runtime',struct('BuildAction','Build','RunOnBoot',false),'SPI',struct('SPI0CE0BusSpeed','500','SPI0CE1BusSpeed','500'),'CAN',struct('CANBusSpeed','500','CANOscillatorFrequency','16','InterruptPin','12','AllowAllFilter',1,'Buffer0IDType','Normal','AcceptanceMask0','0','AcceptanceFilter0','255','AcceptanceFilter1','255','Buffer1IDType','Normal','AcceptanceMask1','0','AcceptanceFilter2','255','AcceptanceFilter3','255','AcceptanceFilter4','255','AcceptanceFilter5','255'),'MQTT',struct('BrokerAddress','mqtt.thingspeak.com','Username','','Password','','ClientID',''),'SimulinkIO',struct('Enable_SimulinkIO',1,'TransportLayerType','TCP/IP','ModelTransportDataFcn','codertarget.raspi.ioclient.getModelTransportInfo','ServerDeployFcn','codertarget.raspi.ioclient.deployServer'),'DataVersion','2016.02'));   % CoderTargetData
            platformSelection = evalin('base','platformSelection');
            
            if (platformSelection == 1 || platformSelection == 3 || platformSelection == 4 ||...
                platformSelection == 5 || platformSelection == 7 || platformSelection == 8 ||...
                platformSelection == 10 || platformSelection == 11)
            
                FileName = app.AvailableDiagramsDropDown.Value;
                assignin('base','WhoAmI',1);
                assignin('base','simMode',0);
                if (platformSelection == 1 || platformSelection == 5)
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED, BLACK, BLUE, or RED + ARM/Stream PhaseSpace to Platform'],'commented','off')
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED+BLACK, or RED+BLACK+ARM/Obtain RED States/Stream PhaseSpace to Platform'],'commented','on')
                else
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED, BLACK, BLUE, or RED + ARM/Stream PhaseSpace to Platform'],'commented','on')
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED+BLACK, or RED+BLACK+ARM/Obtain RED States/Stream PhaseSpace to Platform'],'commented','off')                
                end
                raspberrypi('192.168.0.100','pi','raspberry');
                %slbuild(FileName(1:(end-4)));
                rtwbuild(FileName(1:(end-4)));
                assignin('base','simMode',1);
                
            else
                
                ed = errordlg("Can't compile to RED if it has not been selected as 'active' under 'Active Platforms.'","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end
        end

        % Button pushed function: DeployCodeBLACKButton
        function DeployCodeBLACKButtonPushed(app, event)
            waitfor(warndlg('Warning: Check which "Deploy" option is currently selected under the "Hardware" simulink tab. It should automatically change to "Build" when this warning is dismissed. If "Build, Deploy, and Run" is selected, the experiment will start immediately after building!','Deployment Options'));
                       
            FileName = app.AvailableDiagramsDropDown.Value;
            cs = getConfigSet(FileName(1:end-4),'Configuration');
            cs.set_param('Name', 'Configuration'); % Name
            cs.set_param('Description', ''); % Description
            cs_componentCC = CoderTarget.SettingsController;
            cs.attachComponent(cs_componentCC);
            cs.set_param('CoderTargetData', struct('UseCoderTarget',true,'TargetHardware','Raspberry Pi','ConnectionInfo',struct('XCPonTCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false),'TCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false)),'ExtMode',struct('Configuration','XCP on TCP/IP','signalBufferSize',1000000),'RTOS','Linux','RTOSBaseRateTaskPriority','40','DetectTaskOverruns',0,'Scheduler_interrupt_source',0,'BoardParameters',struct('DeviceAddress','192.168.0.101','Username','pi','Password','raspberry','BuildDir','/home/pi/ExperimentSoftware/'),'Runtime',struct('BuildAction','Build','RunOnBoot',false),'SPI',struct('SPI0CE0BusSpeed','500','SPI0CE1BusSpeed','500'),'CAN',struct('CANBusSpeed','500','CANOscillatorFrequency','16','InterruptPin','12','AllowAllFilter',1,'Buffer0IDType','Normal','AcceptanceMask0','0','AcceptanceFilter0','255','AcceptanceFilter1','255','Buffer1IDType','Normal','AcceptanceMask1','0','AcceptanceFilter2','255','AcceptanceFilter3','255','AcceptanceFilter4','255','AcceptanceFilter5','255'),'MQTT',struct('BrokerAddress','mqtt.thingspeak.com','Username','','Password','','ClientID',''),'SimulinkIO',struct('Enable_SimulinkIO',1,'TransportLayerType','TCP/IP','ModelTransportDataFcn','codertarget.raspi.ioclient.getModelTransportInfo','ServerDeployFcn','codertarget.raspi.ioclient.deployServer'),'DataVersion','2016.02'));   % CoderTargetData
            platformSelection = evalin('base','platformSelection');
            if (platformSelection == 2 || platformSelection == 3 || platformSelection == 4 ||...
                platformSelection == 8 || platformSelection == 9 || platformSelection == 11)
            
                FileName = app.AvailableDiagramsDropDown.Value;
                assignin('base','WhoAmI',2);
                assignin('base','simMode',0);
                if (platformSelection == 2)
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED, BLACK, BLUE, or RED + ARM/Stream PhaseSpace to Platform'],'commented','off')
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED+BLACK, or RED+BLACK+ARM/Obtain RED States/Stream PhaseSpace to Platform'],'commented','on')
                else
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED, BLACK, BLUE, or RED + ARM/Stream PhaseSpace to Platform'],'commented','on')
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED+BLACK, or RED+BLACK+ARM/Obtain RED States/Stream PhaseSpace to Platform'],'commented','off')                
                end              
                raspberrypi('192.168.0.101','pi','raspberry');
                %slbuild(FileName(1:(end-4)));
                rtwbuild(FileName(1:(end-4)));
                assignin('base','simMode',1);
                
            else
                
                ed = errordlg("Can't compile to BLACK if it has not been selected as 'active' under 'Active Platforms.'","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end
        end

        % Button pushed function: DeployCodeBLUEButton
        function DeployCodeBLUEButtonPushed(app, event)
            waitfor(warndlg('Warning: Check which "Deploy" option is currently selected under the "Hardware" simulink tab. It should automatically change to "Build" when this warning is dismissed. If "Build, Deploy, and Run" is selected, the experiment will start immediately after building!','Deployment Options'));
                        
            FileName = app.AvailableDiagramsDropDown.Value;
            cs = getConfigSet(FileName(1:end-4),'Configuration');
            cs.set_param('Name', 'Configuration'); % Name
            cs.set_param('Description', ''); % Description
            cs_componentCC = CoderTarget.SettingsController;
            cs.attachComponent(cs_componentCC);
            cs.set_param('CoderTargetData', struct('UseCoderTarget',true,'TargetHardware','Raspberry Pi','ConnectionInfo',struct('XCPonTCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false),'TCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false)),'ExtMode',struct('Configuration','XCP on TCP/IP','signalBufferSize',1000000),'RTOS','Linux','RTOSBaseRateTaskPriority','40','DetectTaskOverruns',0,'Scheduler_interrupt_source',0,'BoardParameters',struct('DeviceAddress','192.168.0.102','Username','pi','Password','raspberry','BuildDir','/home/pi/ExperimentSoftware/'),'Runtime',struct('BuildAction','Build','RunOnBoot',false),'SPI',struct('SPI0CE0BusSpeed','500','SPI0CE1BusSpeed','500'),'CAN',struct('CANBusSpeed','500','CANOscillatorFrequency','16','InterruptPin','12','AllowAllFilter',1,'Buffer0IDType','Normal','AcceptanceMask0','0','AcceptanceFilter0','255','AcceptanceFilter1','255','Buffer1IDType','Normal','AcceptanceMask1','0','AcceptanceFilter2','255','AcceptanceFilter3','255','AcceptanceFilter4','255','AcceptanceFilter5','255'),'MQTT',struct('BrokerAddress','mqtt.thingspeak.com','Username','','Password','','ClientID',''),'SimulinkIO',struct('Enable_SimulinkIO',1,'TransportLayerType','TCP/IP','ModelTransportDataFcn','codertarget.raspi.ioclient.getModelTransportInfo','ServerDeployFcn','codertarget.raspi.ioclient.deployServer'),'DataVersion','2016.02'));   % CoderTargetData
            platformSelection = evalin('base','platformSelection');
            if (platformSelection == 6 || platformSelection == 7 || platformSelection == 8 ||...
                platformSelection == 9 || platformSelection == 10 || platformSelection == 11)
            
                FileName = app.AvailableDiagramsDropDown.Value;
                assignin('base','WhoAmI',3);
                assignin('base','simMode',0);
                if (platformSelection == 1 || platformSelection == 2 || platformSelection == 5)
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED, BLACK, BLUE, or RED + ARM/Stream PhaseSpace to Platform'],'commented','off')
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED+BLACK, or RED+BLACK+ARM/Obtain RED States/Stream PhaseSpace to Platform'],'commented','on')
                else
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED, BLACK, BLUE, or RED + ARM/Stream PhaseSpace to Platform'],'commented','on')
                    set_param([FileName(1:(end-4)) '/Grab PhaseSpace Data via UDP/Use Hardware to Obtain States/Using RED+BLACK, or RED+BLACK+ARM/Obtain RED States/Stream PhaseSpace to Platform'],'commented','off')                
                end               
                raspberrypi('192.168.0.102','pi','raspberry');
                %slbuild(FileName(1:(end-4))) ;
                rtwbuild(FileName(1:(end-4)));
                assignin('base','simMode',1);
                
            else
                
                ed = errordlg("Can't compile to BLUE if it has not been selected as 'active' under 'Active Platforms.'","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            % Check which platforms have been selected by the user:
            platformSelection = evalin('base','platformSelection');
                       
            % Grab the diagram name:
            FileName = app.AvailableDiagramsDropDown.Value;
           
            % Start the selected diagram:
            if (platformSelection == 1)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4))); % Run Model
                
                
            elseif (platformSelection == 2)
                
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                runModel(BLACK,FileName(1:(end-4)));
                
                                  
            elseif (platformSelection == 3)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
                
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                runModel(BLACK,FileName(1:(end-4)));
                
                
            elseif (platformSelection == 4)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
                
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                runModel(BLACK,FileName(1:(end-4)));
               
                
            elseif (platformSelection == 5)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
                
                
            elseif (platformSelection == 6)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                runModel(BLUE,FileName(1:(end-4)));
                
            elseif (platformSelection == 7)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                runModel(BLUE,FileName(1:(end-4)));
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
                
                
            elseif (platformSelection == 8)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
               
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                runModel(BLUE,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                runModel(BLACK,FileName(1:(end-4)));  
                
            elseif (platformSelection == 9)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                runModel(BLUE,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                runModel(BLACK,FileName(1:(end-4)));
                
            elseif (platformSelection == 10)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                runModel(BLUE,FileName(1:(end-4)));
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
                
            elseif (platformSelection == 11)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                runModel(RED,FileName(1:(end-4)));
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                runModel(BLUE,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                runModel(BLACK,FileName(1:(end-4))); 
                
            end
            
            %pause(5);
                       
            % Run the PhaseSpace executable:
            % system('StreamData.exe &');
            
            % Check if the user has opted for real-time telemetry:
            if (app.RealTimeTelemetryCheckBox.Value == 1)
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
               
                while 1
                    
                    if (app.Interruptor == 1)
                        app.Interruptor = 0;
                        break;
                    end
                    
                    mssg = judp('receive',26000,4096); 
                    time = typecast(mssg,'double');
                    app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(time),' s');
                    drawnow;                    
                     
                end
                             
            end
            
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            % Check which platforms have been selected by the user:
            platformSelection = evalin('base','platformSelection');
            
            % Grab the diagram name:
            FileName = app.AvailableDiagramsDropDown.Value;
           
            % Start the selected diagram:
            if (platformSelection == 1)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
              
                runModel(RED,'CleanShutdown');  
                pause(5);
                stopModel(RED,'CleanShutdown'); 
                
            elseif (platformSelection == 2)
                
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                stopModel(BLACK,FileName(1:(end-4)));
                
                runModel(BLACK,'CleanShutdown');  
                pause(5);
                stopModel(BLACK,'CleanShutdown'); 
                                  
            elseif (platformSelection == 3)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                stopModel(BLACK,FileName(1:(end-4)));
                
                runModel(RED,'CleanShutdown');  
                runModel(BLACK,'CleanShutdown');  
                pause(5);
                stopModel(RED,'CleanShutdown'); 
                stopModel(BLACK,'CleanShutdown');
                
            elseif (platformSelection == 4)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                stopModel(BLACK,FileName(1:(end-4)));
                runModel(RED,'CleanShutdown');  
                runModel(BLACK,'CleanShutdown');  
                pause(5);
                stopModel(RED,'CleanShutdown'); 
                stopModel(BLACK,'CleanShutdown');                
                
            elseif (platformSelection == 5)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                runModel(RED,'CleanShutdown');   
                pause(5);
                stopModel(RED,'CleanShutdown');               
                
            elseif (platformSelection == 6)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                stopModel(BLUE,FileName(1:(end-4)));
                runModel(BLUE,'CleanShutdown');  
                pause(5);
                stopModel(BLUE,'CleanShutdown'); 
                
            elseif (platformSelection == 7)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                stopModel(BLUE,FileName(1:(end-4)));
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                runModel(RED,'CleanShutdown');  
                runModel(BLUE,'CleanShutdown');  
                pause(5);
                stopModel(RED,'CleanShutdown'); 
                stopModel(BLUE,'CleanShutdown');                
                
            elseif (platformSelection == 8)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                stopModel(BLUE,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                stopModel(BLACK,FileName(1:(end-4))); 
                runModel(RED,'CleanShutdown');  
                runModel(BLACK,'CleanShutdown');
                runModel(BLUE,'CleanShutdown');
                pause(5);
                stopModel(RED,'CleanShutdown'); 
                stopModel(BLACK,'CleanShutdown');      
                stopModel(BLUE,'CleanShutdown');
                
            elseif (platformSelection == 9)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                stopModel(BLUE,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                stopModel(BLACK,FileName(1:(end-4)));
                runModel(BLACK,'CleanShutdown');
                runModel(BLUE,'CleanShutdown');
                pause(5);
                stopModel(BLACK,'CleanShutdown');      
                stopModel(BLUE,'CleanShutdown');                
                
            elseif (platformSelection == 10)
                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                stopModel(BLUE,FileName(1:(end-4)));
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                runModel(RED,'CleanShutdown');  
                runModel(BLUE,'CleanShutdown');
                pause(5);
                stopModel(RED,'CleanShutdown');      
                stopModel(BLUE,'CleanShutdown');
                
            elseif (platformSelection == 11)
                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                stopModel(RED,FileName(1:(end-4)));
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                stopModel(BLUE,FileName(1:(end-4)));
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                stopModel(BLACK,FileName(1:(end-4)));
                runModel(RED,'CleanShutdown');  
                runModel(BLACK,'CleanShutdown');
                runModel(BLUE,'CleanShutdown');
                pause(5);
                stopModel(RED,'CleanShutdown'); 
                stopModel(BLACK,'CleanShutdown');      
                stopModel(BLUE,'CleanShutdown');
            end
            
%             !taskkill -f -im StreamData.exe
%             !taskkill -f -im cmd.exe
            
        end

        % Button pushed function: SaveSimulationButton
        function SaveSimulationButtonPushed(app, event)
            % Grab the file name from the drop down menu:
            
            W = evalin("base","whos"); 
            doesPacketExist = ismember('dataPacket',[W(:).name]);
            
            if (sum(doesPacketExist) == 10)
                
                dataPacket     = evalin('base', 'dataPacket');
                
                % Move the file to a folder for storage:
                
                datetime       = clock;
                
                foldername = ['SimulationData_' num2str(datetime(1)) '_' ...
                    num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                num2str(datetime(4)) '_' num2str(datetime(5))];
                
                save('dataPacket_SIM.mat','dataPacket');
                
                mkdir([cd '\Saved Data\' foldername '\']);
                
                movefile([cd '\dataPacket_SIM.mat'],...
                [cd '\Saved Data\' foldername '\dataPacket_SIM.mat']);
                       
            else 
                
                ed = errordlg("The variable 'dataPacket' does not exist in the base workspace. Cannot save data.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end
        end

        % Button pushed function: SaveExperimentButton
        function SaveExperimentButtonPushed(app, event)
            % Grab the file name from the drop down menu:
            Choice = questdlg('Warning: The experiment must be terminated before exporting any data. If you have not ended the experiment, select "Cancel", otherwise select "Proceed".','Data Export Warning','Proceed','Cancel','Proceed');
            switch Choice
                case 'Proceed'
                    % First, load in the selections:
                    RED         = app.REDCheckBox.Value;
                    BLACK       = app.BLACKCheckBox.Value;
                    BLUE        = app.BLUECheckBox.Value;
                    
                    % Grab the diagram name:
                    FileName = app.AvailableDiagramsDropDown.Value;
                    
                    % Get dateTime:
                    datetime       = clock;
                    
                    if RED == 1
                    
                        % Open up a connection to the RED platform:
                    
                        red              = raspberrypi('192.168.0.100', 'pi', 'raspberry');
                    
                        % Retrieve any data file ending with .mat:
                        getFile(red,['./ExperimentSoftware/' FileName((1:end-4)) '*.mat']);
                    
                        % Delete the file from the RP3:
                    
                        deleteFile(red,['./ExperimentSoftware/' FileName((1:end-4)) '*.mat']);
                        % Move the file to a folder for storage:
                    
                        foldername_RED = ['ExperimentData_RED_' num2str(datetime(1)) '_' ...
                            num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                        num2str(datetime(4)) '_' num2str(datetime(5))];
                    
                        % Combine data:
                        
                        app.CombineData([cd '\*.mat']);
                        
                        % Move all data to new folder:
                    
                        movefile([cd '\' FileName((1:end-4)) '*.mat'],...
                        [cd '\Saved Data\' foldername_RED '\']);
                    
                    end
                    
                    if BLACK == 1
                    
                        % Open up a connection to the BLACK platform:
                    
                        black              = raspberrypi('192.168.0.101', 'pi', 'raspberry');
                    
                        % Retrieve any data file ending with .mat:
                    
                        getFile(black,['./ExperimentSoftware/' FileName((1:end-4)) '*.mat']);
                    
                        % Delete the file from the RP3:
                    
                        deleteFile(black,['./ExperimentSoftware/' FileName((1:end-4)) '*.mat']);
                    
                        % Move the file to a folder for storage:
                    
                        foldername_BLACK = ['ExperimentData_BLACK_' num2str(datetime(1)) '_' ...
                            num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                        num2str(datetime(4)) '_' num2str(datetime(5))];
                    
                        % Combine data:
                        
                        app.CombineData([cd '\*.mat']);
                        
                        % Move all data to new folder:
                    
                        movefile([cd '\' FileName((1:end-4)) '*.mat'],...
                        [cd '\Saved Data\' foldername_BLACK '\']);
                    
                    end
                    
                    if BLUE == 1
                    
                        % Open up a connection to the BLUE platform:
                    
                        blue              = raspberrypi('192.168.0.102', 'pi', 'raspberry');
                    
                        % Retrieve any data file ending with .mat:
                    
                        getFile(blue,['./ExperimentSoftware/' FileName((1:end-4)) '*.mat']);
                    
                        % Delete the file from the RP3:
                    
                        deleteFile(blue,['./ExperimentSoftware/' FileName((1:end-4)) '*.mat']);
                                
                        % Move the file to a folder for storage:
                        
                        foldername_BLUE = ['ExperimentData_BLUE_' num2str(datetime(1)) '_' ...
                            num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                        num2str(datetime(4)) '_' num2str(datetime(5))];
                                    
                        % Combine data:
                        
                        app.CombineData([cd '\*.mat']);
                        
                        % Move all data to new folder:
                        
                        movefile([cd '\' FileName((1:end-4)) '*.mat'],...
                        [cd '\Saved Data\' foldername_BLUE '\']);
                    
                    end
                case 'Cancel'
                    % Do nothing.
            end

            
        end

        % Button pushed function: PingREDButton
        function PingREDButtonPushed(app, event)
            
            trytime = char(datetime(now,'ConvertFrom','datenum'));
            try
               RED = raspberrypi('192.168.0.100', 'pi', 'raspberry');
               app.REDConnectedLamp.Color = [0.00,1.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP:' RED.DeviceAddress newline 'Port:' num2str(RED.Port)];
            catch 
               app.REDConnectedLamp.Color = [1.00,0.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end
        end

        % Button pushed function: PingBLACKButton
        function PingBLACKButtonPushed(app, event)
            trytime = char(datetime(now,'ConvertFrom','datenum'));
            try
               BLACK = raspberrypi('192.168.0.101', 'pi', 'raspberry');
               app.BLACKConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP:' BLACK.DeviceAddress newline 'Port:' num2str(BLACK.Port)];
            catch 
               app.BLACKConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Failed...'];              
            end            
        end

        % Button pushed function: PingBLUEButton
        function PingBLUEButtonPushed(app, event)
            trytime = char(datetime(now,'ConvertFrom','datenum'));
            try
               BLUE = raspberrypi('192.168.0.102', 'pi', 'raspberry');
               app.BLUEConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP:' BLUE.DeviceAddress newline 'Port:' num2str(BLUE.Port)];
            catch 
               app.BLUEConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Failed...'];              
            end              
        end

        % Callback function
        function SoftwareInstallationMenuSelected(app, event)
            web('https://youtu.be/d77dXB41VeI','-browser' );
        end

        % Menu selected function: SoftwareFolderStructureMenu
        function SoftwareFolderStructureMenuSelected(app, event)
            web('https://youtu.be/1Ly6PqwiL1w','-browser' );
        end

        % Menu selected function: PhaseSpaceCodeMenu
        function PhaseSpaceCodeMenuSelected(app, event)
            web('https://youtu.be/0DmQvlUzcDc','-browser' );
        end

        % Menu selected function: ExperimentSourceCodeMenu
        function ExperimentSourceCodeMenuSelected(app, event)
            web('https://youtu.be/nVwOGg06n6c','-browser' );
        end

        % Menu selected function: DeviceDriversSinksMenu
        function DeviceDriversSinksMenuSelected(app, event)
            web('https://youtu.be/xqzN2uYfsFM','-browser' );
        end

        % Menu selected function: DeviceDriversSourcesMenu
        function DeviceDriversSourcesMenuSelected(app, event)
            web('https://youtu.be/kHlCK_5decQ','-browser' );
        end

        % Menu selected function: CustomSimulinkLibraryMenu
        function CustomSimulinkLibraryMenuSelected(app, event)
            web('https://youtu.be/KlgUwF5Puzk','-browser' );
        end

        % Button pushed function: FireREDThrustersButton
        function FireREDThrustersButtonPushed(app, event)
            RED = raspberrypi('192.168.0.100','pi','raspberry');
            runModel(RED,'ValidateThrustersRED'); 
        end

        % Button pushed function: OpenREDCMDShell
        function OpenREDCMDShellButtonPushed(app, event)
            RED = raspberrypi('192.168.0.100','pi','raspberry');
            openShell(RED);             
        end

        % Button pushed function: FireBLACKThrustersButton
        function FireBLACKThrustersButtonPushed(app, event)
            BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            runModel(BLACK,'ValidateThrustersBLACK');             
        end

        % Button pushed function: OpenBLACKCMDShell
        function OpenBLACKCMDShellButtonPushed(app, event)
            BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            openShell(BLACK);              
        end

        % Button pushed function: OpenBLUECMDShell
        function OpenBLUECMDShellButtonPushed(app, event)
            BLUE = raspberrypi('192.168.0.102','pi','raspberry');
            openShell(BLUE);              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SPOT30GUIUIFigure = uifigure('Visible', 'off');
            app.SPOT30GUIUIFigure.Name = 'SPOT 3.0 GUI';
            app.FileMenu = uimenu(app.SPOT30GUIUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveGUIMenu = uimenu(app.FileMenu);
            app.SaveGUIMenu.Text = 'Save GUI';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.Text = 'Help';
            app.YouTubeTutorialsMenu = uimenu(app.HelpMenu);
            app.YouTubeTutorialsMenu.Text = 'YouTube Tutorials';
            app.SoftwareInstallationMenu = uimenu(app.YouTubeTutorialsMenu);
            app.SoftwareInstallationMenu.Text = 'Software Installation';
            app.SoftwareFolderStructureMenu = uimenu(app.YouTubeTutorialsMenu);
            app.SoftwareFolderStructureMenu.MenuSelectedFcn = createCallbackFcn(app, @SoftwareFolderStructureMenuSelected, true);
            app.SoftwareFolderStructureMenu.Text = 'Software Folder Structure';
            app.PhaseSpaceCodeMenu = uimenu(app.YouTubeTutorialsMenu);
            app.PhaseSpaceCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @PhaseSpaceCodeMenuSelected, true);
            app.PhaseSpaceCodeMenu.Text = 'PhaseSpace Code';
            app.ExperimentSourceCodeMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ExperimentSourceCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @ExperimentSourceCodeMenuSelected, true);
            app.ExperimentSourceCodeMenu.Text = 'Experiment Source Code';
            app.DeviceDriversSinksMenu = uimenu(app.YouTubeTutorialsMenu);
            app.DeviceDriversSinksMenu.MenuSelectedFcn = createCallbackFcn(app, @DeviceDriversSinksMenuSelected, true);
            app.DeviceDriversSinksMenu.Text = 'Device Drivers (Sinks)';
            app.DeviceDriversSourcesMenu = uimenu(app.YouTubeTutorialsMenu);
            app.DeviceDriversSourcesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeviceDriversSourcesMenuSelected, true);
            app.DeviceDriversSourcesMenu.Text = 'Device Drivers (Sources)';
            app.CustomSimulinkLibraryMenu = uimenu(app.YouTubeTutorialsMenu);
            app.CustomSimulinkLibraryMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomSimulinkLibraryMenuSelected, true);
            app.CustomSimulinkLibraryMenu.Text = 'Custom Simulink Library';
            app.MassPropertiesPanel = uipanel(app.SPOT30GUIUIFigure);
            app.MassPropertiesPanel.Title = 'Mass Properties';
            app.REDMassLabel = uilabel(app.MassPropertiesPanel);
            app.REDMassLabel.Text = 'RED Mass:';
            app.REDInertiaLabel = uilabel(app.MassPropertiesPanel);
            app.REDInertiaLabel.Text = 'RED Inertia';
            app.BLACKMassLabel = uilabel(app.MassPropertiesPanel);
            app.BLACKMassLabel.Text = 'BLACK Mass:';
            app.BLACKInertiaLabel = uilabel(app.MassPropertiesPanel);
            app.BLACKInertiaLabel.Text = 'BLACK Inertia:';
            app.BLUEMassLabel = uilabel(app.MassPropertiesPanel);
            app.BLUEMassLabel.Text = 'BLUE Mass:';
            app.BLUEInertiaLabel = uilabel(app.MassPropertiesPanel);
            app.BLUEInertiaLabel.Text = 'BLUE Inertia:';
            app.MassREDEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.MassREDEditField.ValueDisplayFormat = '%.4f';
            app.MassREDEditField.ValueChangedFcn = createCallbackFcn(app, @MassREDEditFieldValueChanged, true);
            app.MassREDEditField.Value = 16.9478;
            app.InertiaREDEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.InertiaREDEditField.ValueDisplayFormat = '%.4f';
            app.InertiaREDEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaREDEditFieldValueChanged, true);
            app.InertiaREDEditField.Value = 0.2709;
            app.MassBLACKEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.MassBLACKEditField.ValueDisplayFormat = '%.4f';
            app.MassBLACKEditField.ValueChangedFcn = createCallbackFcn(app, @MassBLACKEditFieldValueChanged, true);
            app.MassBLACKEditField.Value = 12.3341;
            app.InertiaBLACKEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.InertiaBLACKEditField.ValueDisplayFormat = '%.4f';
            app.InertiaBLACKEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaBLACKEditFieldValueChanged, true);
            app.InertiaBLACKEditField.Value = 0.188;
            app.MassBLUEEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.MassBLUEEditField.ValueDisplayFormat = '%.4f';
            app.MassBLUEEditField.ValueChangedFcn = createCallbackFcn(app, @MassBLUEEditFieldValueChanged, true);
            app.MassBLUEEditField.Value = 12.7621;
            app.InertiaBLUEEditField = uieditfield(app.MassPropertiesPanel, 'numeric');
            app.InertiaBLUEEditField.ValueDisplayFormat = '%.4f';
            app.InertiaBLUEEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaBLUEEditFieldValueChanged, true);
            app.InertiaBLUEEditField.Value = 0.193;
            app.MassREDLabel = uilabel(app.MassPropertiesPanel);
            app.MassREDLabel.Text = 'Kg';
            app.MassBLACKLabel = uilabel(app.MassPropertiesPanel);
            app.MassBLACKLabel.Text = 'Kg';
            app.MassBLUELabel = uilabel(app.MassPropertiesPanel);
            app.MassBLUELabel.Text = 'Kg';
            app.InertiaREDLabel = uilabel(app.MassPropertiesPanel);
            app.InertiaREDLabel.Text = 'Kg*m**2';
            app.InertiaBLACKLabel = uilabel(app.MassPropertiesPanel);
            app.InertiaBLACKLabel.Text = 'Kg*m**2';
            app.InertiaBLUELabel = uilabel(app.MassPropertiesPanel);
            app.InertiaBLUELabel.Text = 'Kg*m**2';
            app.AuxParametersPanel = uipanel(app.SPOT30GUIUIFigure);
            app.AuxParametersPanel.Title = 'Aux Parameters';
            app.TimeLabel = uilabel(app.AuxParametersPanel);
            app.TimeLabel.Text = 'Time:';
            app.TotalTimeEditField = uieditfield(app.AuxParametersPanel, 'numeric');
            app.TotalTimeEditField.ValueDisplayFormat = '%.1f';
            app.TotalTimeEditField.Editable = 'off';
            app.TotalTimeEditField.Value = 500;
            app.sLabel = uilabel(app.AuxParametersPanel);
            app.sLabel.Text = 's';
            app.RateLabel = uilabel(app.AuxParametersPanel);
            app.RateLabel.Text = 'Rate:';
            app.SampleRateEditField = uieditfield(app.AuxParametersPanel, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.f';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Value = 100;
            app.HzLabel = uilabel(app.AuxParametersPanel);
            app.HzLabel.Text = 'Hz';
            app.ActivePlatformsPanel = uipanel(app.SPOT30GUIUIFigure);
            app.ActivePlatformsPanel.Title = 'Active Platforms';
            app.REDCheckBox = uicheckbox(app.ActivePlatformsPanel);
            app.REDCheckBox.Text = 'RED ';
            app.BLACKCheckBox = uicheckbox(app.ActivePlatformsPanel);
            app.BLACKCheckBox.Text = 'BLACK ';
            app.BLUECheckBox = uicheckbox(app.ActivePlatformsPanel);
            app.BLUECheckBox.Text = 'BLUE ';
            app.ARMCheckBox = uicheckbox(app.ActivePlatformsPanel);
            app.ARMCheckBox.Text = 'ARM';
            app.ConfirmOptionsButton = uibutton(app.SPOT30GUIUIFigure, 'push');
            app.ConfirmOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmOptionsButtonPushed, true);
            app.ConfirmOptionsButton.Text = 'Confirm Options';
            app.LoadSelectedDiagramButton = uibutton(app.SPOT30GUIUIFigure, 'push');
            app.LoadSelectedDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedDiagramButtonPushed, true);
            app.LoadSelectedDiagramButton.Text = 'Load Selected Diagram';
            app.AvailableDiagramsDropDownLabel = uilabel(app.SPOT30GUIUIFigure);
            app.AvailableDiagramsDropDownLabel.Text = 'Available Diagrams:';
            app.AvailableDiagramsDropDown = uidropdown(app.SPOT30GUIUIFigure);
            app.ExperimentPanel = uipanel(app.SPOT30GUIUIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.ExperimentOptionsPanel = uipanel(app.ExperimentPanel);
            app.ExperimentOptionsPanel.Title = 'Experiment Options';
            app.StartExperimentButton = uibutton(app.ExperimentOptionsPanel, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.StopExperimentButton = uibutton(app.ExperimentOptionsPanel, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveExperimentButton = uibutton(app.ExperimentOptionsPanel, 'push');
            app.SaveExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExperimentButtonPushed, true);
            app.SaveExperimentButton.Text = 'Save Experiment';
            app.RealTimeTelemetryCheckBox = uicheckbox(app.ExperimentOptionsPanel);
            app.RealTimeTelemetryCheckBox.Text = 'Real-Time Telemetry';
            app.TabGroup = uitabgroup(app.ExperimentPanel);
            app.REDControlsTab = uitab(app.TabGroup);
            app.REDControlsTab.Title = 'RED Controls';
            app.REDPucksOnButton = uibutton(app.REDControlsTab, 'push');
            app.REDPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOnButtonPushed, true);
            app.REDPucksOnButton.Text = 'Pucks On';
            app.REDPucksOffButton = uibutton(app.REDControlsTab, 'push');
            app.REDPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOffButtonPushed, true);
            app.REDPucksOffButton.Text = 'Pucks Off';
            app.DeployCodeREDButton = uibutton(app.REDControlsTab, 'push');
            app.DeployCodeREDButton.ButtonPushedFcn = createCallbackFcn(app, @DeployCodeREDButtonPushed, true);
            app.DeployCodeREDButton.Text = 'Deploy Code';
            app.REDDebuggingPanel = uipanel(app.REDControlsTab);
            app.REDDebuggingPanel.Title = 'Debugging';
            app.FireREDThrustersButton = uibutton(app.REDDebuggingPanel, 'push');
            app.FireREDThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireREDThrustersButtonPushed, true);
            app.FireREDThrustersButton.Text = 'Fire Thrusters';
            app.PingREDButton = uibutton(app.REDDebuggingPanel, 'push');
            app.PingREDButton.ButtonPushedFcn = createCallbackFcn(app, @PingREDButtonPushed, true);
            app.PingREDButton.Text = 'Ping RP3';
            app.REDTextArea = uitextarea(app.REDDebuggingPanel);
            app.REDTextArea.Editable = 'off';
            app.REDConnectedLamp = uilamp(app.REDDebuggingPanel);
            app.OpenREDCMDShell = uibutton(app.REDDebuggingPanel, 'push');
            app.OpenREDCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenREDCMDShellButtonPushed, true);
            app.OpenREDCMDShell.Text = 'Open CMD Shell';
            app.BLACKControlsTab = uitab(app.TabGroup);
            app.BLACKControlsTab.Title = 'BLACK Controls';
            app.BLACKPucksOnButton = uibutton(app.BLACKControlsTab, 'push');
            app.BLACKPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOnButtonPushed, true);
            app.BLACKPucksOnButton.Text = 'Pucks On';
            app.BLACKPucksOffButton = uibutton(app.BLACKControlsTab, 'push');
            app.BLACKPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOffButtonPushed, true);
            app.BLACKPucksOffButton.Text = 'Pucks Off';
            app.DeployCodeBLACKButton = uibutton(app.BLACKControlsTab, 'push');
            app.DeployCodeBLACKButton.ButtonPushedFcn = createCallbackFcn(app, @DeployCodeBLACKButtonPushed, true);
            app.DeployCodeBLACKButton.Text = 'Deploy Code';
            app.BLACKDebuggingPanel = uipanel(app.BLACKControlsTab);
            app.BLACKDebuggingPanel.Title = 'Debugging';
            app.FireBLACKThrustersButton = uibutton(app.BLACKDebuggingPanel, 'push');
            app.FireBLACKThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLACKThrustersButtonPushed, true);
            app.FireBLACKThrustersButton.Text = 'Fire Thrusters';
            app.PingBLACKButton = uibutton(app.BLACKDebuggingPanel, 'push');
            app.PingBLACKButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLACKButtonPushed, true);
            app.PingBLACKButton.Text = 'Ping RP3';
            app.BLACKConnectedLamp = uilamp(app.BLACKDebuggingPanel);
            app.BLACKTextArea = uitextarea(app.BLACKDebuggingPanel);
            app.BLACKTextArea.Editable = 'off';
            app.OpenBLACKCMDShell = uibutton(app.BLACKDebuggingPanel, 'push');
            app.OpenBLACKCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLACKCMDShellButtonPushed, true);
            app.OpenBLACKCMDShell.Text = 'Open CMD Shell';
            app.BLUEControlsTab = uitab(app.TabGroup);
            app.BLUEControlsTab.Title = 'BLUE Controls';
            app.BLUEPucksOnButton = uibutton(app.BLUEControlsTab, 'push');
            app.BLUEPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOnButtonPushed, true);
            app.BLUEPucksOnButton.Text = 'Pucks On';
            app.BLUEPucksOffButton = uibutton(app.BLUEControlsTab, 'push');
            app.BLUEPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOffButtonPushed, true);
            app.BLUEPucksOffButton.Text = 'Pucks Off';
            app.DeployCodeBLUEButton = uibutton(app.BLUEControlsTab, 'push');
            app.DeployCodeBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @DeployCodeBLUEButtonPushed, true);
            app.DeployCodeBLUEButton.Text = 'Deploy Code';
            app.BLUEDebuggingPanel = uipanel(app.BLUEControlsTab);
            app.BLUEDebuggingPanel.Title = 'Debugging';
            app.FireBLUEThrustersButton = uibutton(app.BLUEDebuggingPanel, 'push');
            app.FireBLUEThrustersButton.Text = 'Fire Thrusters';
            app.PingBLUEButton = uibutton(app.BLUEDebuggingPanel, 'push');
            app.PingBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLUEButtonPushed, true);
            app.PingBLUEButton.Text = 'Ping RP3';
            app.BLUETextArea = uitextarea(app.BLUEDebuggingPanel);
            app.BLUETextArea.Editable = 'off';
            app.BLUEConnectedLamp = uilamp(app.BLUEDebuggingPanel);
            app.OpenBLUECMDShell = uibutton(app.BLUEDebuggingPanel, 'push');
            app.OpenBLUECMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLUECMDShellButtonPushed, true);
            app.OpenBLUECMDShell.Text = 'Open CMD Shell';
            app.SimulationPanel = uipanel(app.SPOT30GUIUIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.ExperimentPreview = uiaxes(app.SimulationPanel);
            xlabel(app.ExperimentPreview, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreview, 'X-Coordinate (m)')
            app.SimulationControlsPanel = uipanel(app.SimulationPanel);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.RunSimulationButton = uibutton(app.SimulationControlsPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PlayAnimationButton = uibutton(app.SimulationControlsPanel, 'push');
            app.PlayAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAnimationButtonPushed, true);
            app.PlayAnimationButton.Text = 'Play Animation';
            app.AnimationSpeedButtonGroup = uibuttongroup(app.SimulationControlsPanel);
            app.AnimationSpeedButtonGroup.Title = 'Animation Speed';
            app.AnimationSpeedSlider = uislider(app.AnimationSpeedButtonGroup);
            app.AnimationSpeedSlider.MajorTicks = [0 100];
            app.AnimationSpeedSlider.MajorTickLabels = {'Fast', 'Slow'};
            app.StopAnimationButton = uibutton(app.SimulationControlsPanel, 'state');
            app.StopAnimationButton.Text = 'Stop Animation';
            app.SimExportOptionsPanel = uipanel(app.SimulationPanel);
            app.SimExportOptionsPanel.Title = 'Export Options';
            app.CreateVideoCheckBox = uicheckbox(app.SimExportOptionsPanel);
            app.CreateVideoCheckBox.Text = 'Create Video';
            app.SaveSimulationButton = uibutton(app.SimExportOptionsPanel, 'push');
            app.SaveSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSimulationButtonPushed, true);
            app.SaveSimulationButton.Text = 'Save Simulation ';
            app.Image = uiimage(app.SPOT30GUIUIFigure);
            app.Image.ImageSource = 'SRCL_Logo.png';
            app.SPOT30Label = uilabel(app.SPOT30GUIUIFigure);
            app.SPOT30Label.Text = 'SPOT 3.0';
            app.Version307Label = uilabel(app.SPOT30GUIUIFigure);
            app.Version307Label.Text = 'Version 3.07';
            % Show the figure after all components are created
            app.SPOT30GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v3_07

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SPOT30GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @PopulateGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPOT30GUIUIFigure)
        end
    end
end